classdef templatePicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        includeICheckBox   matlab.ui.control.CheckBox
        FinishedButton     matlab.ui.control.Button
        meanframeLabel     matlab.ui.control.Label
        currentframeLabel  matlab.ui.control.Label
        Button_2           matlab.ui.control.Button
        Button             matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
    end

        
    properties (Access = public)
        Y_temp %image frames
        Y_temp_include %included image frames
        num_frames %number of total image frames
        template_frames %bool array of frames to be included
        current_frame %current frame being displayed
        editing %currently editing, or finished
    end
    
    methods (Access = private)
        
        function updateMean(app)
            app.Y_temp_include = app.Y_temp(:,:,app.template_frames);
            tmp = mean(app.Y_temp_include,3,'omitnan');
            tmp = tmp./prctile(tmp(:),95);
            imshow(tmp,'Parent',app.UIAxes_2);
        end
        
        function updateCurrent(app)
            tmp = app.Y_temp(:,:,app.current_frame);
            tmp = tmp./prctile(tmp(:),95);
            imshow(tmp,'Parent',app.UIAxes);
            app.currentframeLabel.Text = ['current frame (' num2str(app.current_frame) '/' num2str(app.num_frames) ')'];
            app.includeICheckBox.Value = app.template_frames(app.current_frame);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Y_temp)
            app.Y_temp = Y_temp;
            app.Y_temp_include = Y_temp;
            app.num_frames = size(Y_temp,3);
            app.template_frames = true(1,app.num_frames);
            app.current_frame = 1;
            app.editing = 'on';
            updateMean(app);
            updateCurrent(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.current_frame = app.current_frame - 1;
            if app.current_frame==0
                app.current_frame = 1;
            else
                updateCurrent(app)
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.current_frame = app.current_frame + 1;
            if app.current_frame>app.num_frames
                app.current_frame = app.num_frames;
            else
                updateCurrent(app)
            end
        end

        % Value changed function: includeICheckBox
        function includeICheckBoxValueChanged(app, event)
            app.template_frames(app.current_frame) = app.includeICheckBox.Value;
            updateMean(app);
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            app.editing = 'off';
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    Button_2Pushed(app, event)
                case 'leftarrow'
                    ButtonPushed(app, event)
                case 'i'
                    app.includeICheckBox.Value = ~app.includeICheckBox.Value;
                    includeICheckBoxValueChanged(app, event);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.currentframeLabel = uilabel(app.UIFigure);
            app.currentframeLabel.Text = 'current frame (1/200)';
            app.meanframeLabel = uilabel(app.UIFigure);
            app.meanframeLabel.Text = 'mean of selected frames';
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.includeICheckBox = uicheckbox(app.UIFigure);
            app.includeICheckBox.ValueChangedFcn = createCallbackFcn(app, @includeICheckBoxValueChanged, true);
            app.includeICheckBox.Text = 'include ("I")';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = templatePicker(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end